classdef main_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        imageInfo             matlab.ui.control.Button
        brightnessSlider      matlab.ui.control.Slider
        text13                matlab.ui.control.Label
        saveImage             matlab.ui.control.Button
        imageDimensionsValue  matlab.ui.control.Label
        imageDimensions       matlab.ui.control.Label
        imageSizeValue        matlab.ui.control.Label
        imageSize             matlab.ui.control.Label
        text8                 matlab.ui.control.Label
        background            matlab.ui.control.Button
        backgroundColor       matlab.ui.container.ButtonGroup
        white                 matlab.ui.control.RadioButton
        black                 matlab.ui.control.RadioButton
        text7                 matlab.ui.control.Label
        text6                 matlab.ui.control.Label
        histogramButton       matlab.ui.control.Button
        thresholdSliderText   matlab.ui.control.Label
        selectImage           matlab.ui.control.Button
        text4                 matlab.ui.control.Label
        text3                 matlab.ui.control.Label
        text2                 matlab.ui.control.Label
        thresholdSlider       matlab.ui.control.Slider
        axes7                 matlab.ui.control.UIAxes
        axes4                 matlab.ui.control.UIAxes
        segmentedImage        matlab.ui.control.UIAxes
        blackAndWhiteImage    matlab.ui.control.UIAxes
        originalImage         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function setImage(app, path, handles)
            global Image;
            Image = imread(path);
            axes(handles.originalImage);
            imshow(Image);
            
            % display image information
            set(handles.imageSizeValue,'String', size(Image));
            set(handles.imageDimensionsValue,'String', ndims(Image));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function main_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to main (see VARARGIN)
            
            % Choose default command line output for main
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            set(handles.histogramButton,'visible','off')
        end

        % Button pushed function: background
        function background_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %call thresholdslide if the background color is changed
            thresholdSlider_Callback(handles.thresholdSlider, eventdata, handles)
        end

        % Value changed function: brightnessSlider
        function brightnessSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global Image;
            %check if the Image is selected by the user
            if(~isempty(Image))
            % if the image is selected then change the brightness of the image using the
            % slider value
            Image = imlocalbrighten(Image,get(hObject,'Value'));
            axes(handles.originalImage)
            % display the image
            imshow(Image)
            end
        end

        % Button pushed function: histogramButton
        function histogramButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global Image;
            global segmentedImage;
            
            origImg = Image;
            
            if(ndims(Image) == 3)
            origImg=rgb2gray(Image);
            end
            
            % display histogram of original image and segmented image
            axes(handles.axes4);
            imhist(origImg)
            ylim([0, 1000])
            
            axes(handles.axes7);
            imhist(segmentedImage)
            
            ylim([0, 1000])
        end

        % Button pushed function: imageInfo
        function imageInfo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global Image;
            global file;
            %check if the Image is selected by the user
            if(~isempty(Image))
            imageinfo(file);
            end
        end

        % Button pushed function: saveImage
        function saveImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global segmentedImage;
            
            %save image to the drive
            [file,path] = uiputfile('*.png','Save Image As');
            % join file name with path
            path = append(path,file);
            imwrite(segmentedImage,path);
        end

        % Button pushed function: selectImage
        function selectImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global file;
            % select image using GUI.
            [file, path] = uigetfile({'*.png;*.jpeg;*.jpg;'},'Select an image');
            % combine the path with file name
            path = append(path,file);
            setImage(app, path, handles);
        end

        % Value changed function: thresholdSlider
        function thresholdSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global Image;
            global segmentedImage;
            global backgroundColor;
            
            % get value from radio buttons
            black = get(handles.black,'Value');
            white = get(handles.white,'Value');
            % assign the background color based on radio button
            if(black)
                backgroundColor = 0;
            else
                backgroundColor = 255;
            end
            
            % Get Threshold slider value
            val = get(hObject, 'Value')
            
            % check if the Image has been selected by the user.
            if(~isempty(Image))
            
            %if image is 2 dimensional then convert it into black and white
            if(ndims(Image) == 2)
            BWImage = imbinarize(Image, val);
            else
            %if the image is 3 dimensional the convert it into grayscale image and then
            %into black and white
            BWImage = imbinarize(rgb2gray(Image), val);
            end
            
            %display image in axis
            axes(handles.blackAndWhiteImage);
            imshow(BWImage);
            axes(handles.segmentedImage)
            segmentedImage = Image;
            
            %temporary variable to change the image
            tempImage = BWImage;
            if(ndims(Image) == 3)
            % create 3 dimensional temp variable with black and white image
            tempImage(:,:,1) = BWImage;
            tempImage(:,:,2) = BWImage;
            tempImage(:,:,3) = BWImage;
            end
            
            % the temporary image variable is used to segment the image and add
            % background color as selected from the radio buttons
            segmentedImage(~tempImage) = backgroundColor;
            
            % display the segmented image
            imshow(segmentedImage);
            
            % display histogram button
            set(handles.histogramButton,'visible','on')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'main';
            app.figure1.Tag = 'figure1';
            app.originalImage = uiaxes(app.figure1);
            app.originalImage.Tag = 'originalImage';
            app.blackAndWhiteImage = uiaxes(app.figure1);
            app.blackAndWhiteImage.Tag = 'blackAndWhiteImage';
            app.segmentedImage = uiaxes(app.figure1);
            app.segmentedImage.Tag = 'segmentedImage';
            app.axes4 = uiaxes(app.figure1);
            app.axes4.Tag = 'axes4';
            app.axes7 = uiaxes(app.figure1);
            app.axes7.Tag = 'axes7';
            app.thresholdSlider = uislider(app.figure1);
            app.thresholdSlider.Limits = [0 1];
            app.thresholdSlider.MajorTicks = [];
            app.thresholdSlider.ValueChangedFcn = createCallbackFcn(app, @thresholdSlider_Callback, true);
            app.thresholdSlider.MinorTicks = [];
            app.thresholdSlider.Tag = 'thresholdSlider';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Original Image';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Segmented Image';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Black and White';
            app.selectImage = uibutton(app.figure1, 'push');
            app.selectImage.ButtonPushedFcn = createCallbackFcn(app, @selectImage_Callback, true);
            app.selectImage.Tag = 'selectImage';
            app.selectImage.Text = 'Select Image';
            app.thresholdSliderText = uilabel(app.figure1);
            app.thresholdSliderText.Tag = 'thresholdSliderText';
            app.thresholdSliderText.Text = 'Threshold';
            app.histogramButton = uibutton(app.figure1, 'push');
            app.histogramButton.ButtonPushedFcn = createCallbackFcn(app, @histogramButton_Callback, true);
            app.histogramButton.Tag = 'histogramButton';
            app.histogramButton.Text = 'Show Histogram';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Original Image Histogram';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Segmented Image Histogram';
            app.backgroundColor = uibuttongroup(app.figure1);
            app.backgroundColor.Title = 'Background Color';
            app.backgroundColor.Tag = 'backgroundColor';
            app.black = uiradiobutton(app.backgroundColor);
            app.black.Tag = 'black';
            app.black.Text = 'Black';
            app.black.Value = true;
            app.white = uiradiobutton(app.backgroundColor);
            app.white.Tag = 'white';
            app.white.Text = 'White';
            app.background = uibutton(app.figure1, 'push');
            app.background.ButtonPushedFcn = createCallbackFcn(app, @background_Callback, true);
            app.background.Tag = 'background';
            app.background.Text = 'Change Background';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Image Information';
            app.imageSize = uilabel(app.figure1);
            app.imageSize.Tag = 'imageSize';
            app.imageSize.Text = 'Image Size';
            app.imageSizeValue = uilabel(app.figure1);
            app.imageSizeValue.Tag = 'imageSizeValue';
            app.imageSizeValue.Text = '';
            app.imageDimensions = uilabel(app.figure1);
            app.imageDimensions.Tag = 'imageDimensions';
            app.imageDimensions.Text = 'Dimensions';
            app.imageDimensionsValue = uilabel(app.figure1);
            app.imageDimensionsValue.Tag = 'imageDimensionsValue';
            app.imageDimensionsValue.Text = '';
            app.saveImage = uibutton(app.figure1, 'push');
            app.saveImage.ButtonPushedFcn = createCallbackFcn(app, @saveImage_Callback, true);
            app.saveImage.Tag = 'saveImage';
            app.saveImage.Text = 'Save Image';
            app.text13 = uilabel(app.figure1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Brightness';
            app.brightnessSlider = uislider(app.figure1);
            app.brightnessSlider.Limits = [0 1];
            app.brightnessSlider.MajorTicks = [];
            app.brightnessSlider.ValueChangedFcn = createCallbackFcn(app, @brightnessSlider_Callback, true);
            app.brightnessSlider.MinorTicks = [];
            app.brightnessSlider.Tag = 'brightnessSlider';
            app.imageInfo = uibutton(app.figure1, 'push');
            app.imageInfo.ButtonPushedFcn = createCallbackFcn(app, @imageInfo_Callback, true);
            app.imageInfo.Tag = 'imageInfo';
            app.imageInfo.Text = 'Image Info';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)main_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end